classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Lbl_EoA_Z      matlab.ui.control.Label
        Lbl_EoA_Y      matlab.ui.control.Label
        Lbl_EoA_X      matlab.ui.control.Label
        Lbl_EoA        matlab.ui.control.Label
        FldEoA_Z       matlab.ui.control.NumericEditField
        FldEoA_Y       matlab.ui.control.NumericEditField
        FldEoA_X       matlab.ui.control.NumericEditField
        BtnCW          matlab.ui.control.StateButton
        BtnCCW         matlab.ui.control.StateButton
        BtnGripperCls  matlab.ui.control.Button
        BtnGripperOpn  matlab.ui.control.Button
        BtnCamera      matlab.ui.control.Button
        BtnStream      matlab.ui.control.Button
        BtnBwdLeft     matlab.ui.control.StateButton
        BtnFwdLeft     matlab.ui.control.StateButton
        BtnBwdRight    matlab.ui.control.StateButton
        BtnFwdRight    matlab.ui.control.StateButton
        BtnLeft        matlab.ui.control.StateButton
        BtnBackward    matlab.ui.control.StateButton
        BtnRight       matlab.ui.control.StateButton
        BtnForward     matlab.ui.control.StateButton
        FldJoint4      matlab.ui.control.NumericEditField
        FldJoint3      matlab.ui.control.NumericEditField
        FldJoint2      matlab.ui.control.NumericEditField
        FldJoint1      matlab.ui.control.NumericEditField
        FldJoint0      matlab.ui.control.NumericEditField
        SldSpeed       matlab.ui.control.Slider
        SPEEDLabel     matlab.ui.control.Label
        SldJoint3      matlab.ui.control.Slider
        Joint3Label    matlab.ui.control.Label
        SldJoint4      matlab.ui.control.Slider
        Joint4Label    matlab.ui.control.Label
        SldJoint2      matlab.ui.control.Slider
        Joint2Label    matlab.ui.control.Label
        SldJoint0      matlab.ui.control.Slider
        Joint0Label    matlab.ui.control.Label
        SldJoint1      matlab.ui.control.Slider
        Joint1Label    matlab.ui.control.Label
        BtnSimulate    matlab.ui.control.Button
        BtnConnect     matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties(Constant)
        %DH Parameters [theta, d, a, alpha], these parameters have been
        %extracted from CoppeliaSim module.
        DH_params = [
            0, 0.1012, 0.0330, 90;    % Joint 0 -> Joint 1
            0, 0.0000, 0.1550, 0;     % Joint 1 -> Joint 2
            0, 0.0000, 0.1348, 0;     % Joint 2 -> Joint 3
            0, 0.0190, 0.0005, -90;   % Joint 3 -> Joint 4
            0, 0.0973, 0.0001, -90    % Joint 4 -> End-effector
            ];

    end
    properties (Access = private)
        %Constants
        structJointTypes = struct( ...
        'REVOLUTE', 1, ...
        'PRISMATIC', 2 ...
    );


        connection % Instance of RobotConnection
        joints %Array of Joint objects
        baseControl %Base control object
        visionSensor %Vision Sensor object
        wheelsSpeed = 1.0; 
        timer_sendPos %clock to call api for joint positions
    end
    
    methods (Access = private)
        
        function retrieveAndInitializeJoints(app)
            % Initialize the joints array as a cell array that can contain objects
            app.joints = cell(1, 7);  % Adjust the size according to the number of joints

            % Retrieve joint handles from CoppeliaSim
            [handleJ0, ~] = app.connection.getHandle('youBotArmJoint0');
            [handleJ1, ~] = app.connection.getHandle('youBotArmJoint1');
            [handleJ2, ~] = app.connection.getHandle('youBotArmJoint2');
            [handleJ3, ~] = app.connection.getHandle('youBotArmJoint3');
            [handleJ4, ~] = app.connection.getHandle('youBotArmJoint4');

            % Retrieve joint handles for prismatic joints (gripper)
            [handleGripper1, ~] = app.connection.getHandle('youBotGripperJoint1');
            [handleGripper2, ~] = app.connection.getHandle('youBotGripperJoint2');

            % Initialize the Joint objects for each slider
            app.joints{1} = Joint(handleJ0, app.structJointTypes.REVOLUTE, -169.0, 169.0, app.connection.sim, app.connection.clientID); % For Joint0
            app.joints{2} = Joint(handleJ1, app.structJointTypes.REVOLUTE, -90.0, 90.0, app.connection.sim, app.connection.clientID);   % For Joint1
            app.joints{3} = Joint(handleJ2, app.structJointTypes.REVOLUTE, -131.0, 131.0, app.connection.sim, app.connection.clientID); % For Joint2
            app.joints{4} = Joint(handleJ3, app.structJointTypes.REVOLUTE, -102.0, 102.0, app.connection.sim, app.connection.clientID); % For Joint3
            app.joints{5} = Joint(handleJ4, app.structJointTypes.REVOLUTE, -90.0, 90.0, app.connection.sim, app.connection.clientID);   % For Joint4

            % Initialize the Joint objects for prismatic joints (gripper)
            app.joints{6} = Joint(handleGripper1, app.structJointTypes.PRISMATIC, 0.0, 0.025,app.connection.sim, app.connection.clientID); % GripperJoint1
            app.joints{7} = Joint(handleGripper2, app.structJointTypes.PRISMATIC, -0.050, 0.0,app.connection.sim, app.connection.clientID); % GripperJoint2

            % Set initial cmdPos to currentPos for each joint
            for i = 1:length(app.joints)
                app.joints{i}.cmdPos = app.joints{i}.currentPos;  % Initialize cmdPos to match currentPos
                app.joints{i}.atPos = true;  % Mark the joint as being at position
            end
            
            % Retrieve wheel handles from CoppeliaSim (use correct object names)
            [handleWheelFR, ~] = app.connection.getHandle('rollingJoint_fr');
            [handleWheelFL, ~] = app.connection.getHandle('rollingJoint_fl');
            [handleWheelRR, ~] = app.connection.getHandle('rollingJoint_rr');
            [handleWheelRL, ~] = app.connection.getHandle('rollingJoint_rl');
            % Store the handles in an array to pass to BaseControl
            wheelHandles = [handleWheelFR, handleWheelFL, handleWheelRR, handleWheelRL];
            % Initialize the BaseControl object
            app.baseControl = BaseControl(wheelHandles);
           
            %Initialize the VisionSensor
            [visionHandle, ~] = app.connection.getHandle('/youBot/kinect/rgb');
            app.visionSensor = VisionSensor(visionHandle);
            

        end

        function setInitialSpeedAfterSimStart(app)
            % Set initial speed for all joints after simulation starts
            for i = 1:length(app.joints)
                if app.joints{i}.type == Joint.structJointTypes.REVOLUTE
                    % Set revolute joint initial speed to 1 rad/sec
                    initialSpeed = 1;
                else
                    % Set prismatic joint initial speed to 0.1 m/s
                    initialSpeed = 0.1;
                end

                % Set the joint velocity after the simulation starts
                app.joints{i}.setVelocity(app.connection.sim, app.connection.clientID, initialSpeed);
            end
        end

        function disableUIControls(app)
            % Disable joint sliders
            app.SldJoint0.Enable = 'off';
            app.SldJoint1.Enable = 'off';
            app.SldJoint2.Enable = 'off';
            app.SldJoint3.Enable = 'off';
            app.SldJoint4.Enable = 'off';

            % Disable speed slider
            app.SldSpeed.Enable = 'off';

            % Disable base control buttons
            app.BtnFwdLeft.Enable = 'off';
            app.BtnBwdLeft.Enable = 'off';
            app.BtnFwdRight.Enable = 'off';
            app.BtnBwdRight.Enable = 'off';
            app.BtnForward.Enable = 'off';
            app.BtnBackward.Enable = 'off';
            app.BtnLeft.Enable = 'off';
            app.BtnRight.Enable = 'off';
            app.BtnCW.Enable = "off";
            app.BtnCCW.Enable = "off";
            app.BtnSimulate.Enable = "off";

            % Disable camera buttons
            app.BtnStream.Enable = 'off';
            app.BtnCamera.Enable = 'off';

            % Disable gripper control buttons
            app.BtnGripperOpn.Enable = 'off';
            app.BtnGripperCls.Enable = 'off';
            
            % Disable field for actual joint pos
            app.FldJoint0.Enable = "off";
            app.FldJoint1.Enable = "off";
            app.FldJoint2.Enable = "off";
            app.FldJoint3.Enable = "off";
            app.FldJoint4.Enable = "off";
            app.FldEoA_X.Enable = "off";
            app.FldEoA_Y.Enable = "off";
            app.FldEoA_Z.Enable = "off";

            %Enable text Labels
            app.Lbl_EoA.Enable = "off";
            app.Lbl_EoA_X.Enable = "off";
            app.Lbl_EoA_Y.Enable = "off";
            app.Lbl_EoA_Z.Enable = "off";
        end

        function enableUIControls(app)
            % Enable joint sliders
            app.SldJoint0.Enable = 'on';
            app.SldJoint1.Enable = 'on';
            app.SldJoint2.Enable = 'on';
            app.SldJoint3.Enable = 'on';
            app.SldJoint4.Enable = 'on';

            % Enable speed slider
            app.SldSpeed.Enable = 'on';

            % Enable base control buttons
            app.BtnFwdLeft.Enable = 'on';
            app.BtnBwdLeft.Enable = 'on';
            app.BtnFwdRight.Enable = 'on';
            app.BtnBwdRight.Enable = 'on';
            app.BtnForward.Enable = 'on';
            app.BtnBackward.Enable = 'on';
            app.BtnLeft.Enable = 'on';
            app.BtnRight.Enable = 'on';
            app.BtnCW.Enable = "on";
            app.BtnCCW.Enable = "on";
            app.BtnSimulate.Enable = "on";

            % Enable camera buttons
            app.BtnStream.Enable = 'on';
            app.BtnCamera.Enable = 'on';

            % Enable gripper control buttons
            app.BtnGripperOpn.Enable = 'on';
            app.BtnGripperCls.Enable = 'on';

            % Disable field for actual joint pos
            app.FldJoint0.Enable = "on";
            app.FldJoint1.Enable = "on";
            app.FldJoint2.Enable = "on";
            app.FldJoint3.Enable = "on";
            app.FldJoint4.Enable = "on";
            app.FldEoA_X.Enable = "on";
            app.FldEoA_Y.Enable = "on";
            app.FldEoA_Z.Enable = "on";
       
            %Enable text Labels
            app.Lbl_EoA.Enable = "on";
            app.Lbl_EoA_X.Enable = "on";
            app.Lbl_EoA_Y.Enable = "on";
            app.Lbl_EoA_Z.Enable = "on";
        end

        function setupSliders(app)

            % Setup Joint sliders based on joint properties
            app.SldJoint0.Limits = [app.joints{1}.lowerLimit, app.joints{1}.upperLimit];
            app.SldJoint1.Limits = [app.joints{2}.lowerLimit, app.joints{2}.upperLimit];
            app.SldJoint2.Limits = [app.joints{3}.lowerLimit, app.joints{3}.upperLimit];
            app.SldJoint3.Limits = [app.joints{4}.lowerLimit, app.joints{4}.upperLimit];
            app.SldJoint4.Limits = [app.joints{5}.lowerLimit, app.joints{5}.upperLimit];

            % Set initial positions for the sliders (if known or retrieved from the simulation)
            app.SldJoint0.Value = app.joints{1}.currentPos;
            app.SldJoint1.Value = app.joints{2}.currentPos;
            app.SldJoint2.Value = app.joints{3}.currentPos;
            app.SldJoint3.Value = app.joints{4}.currentPos;
            app.SldJoint4.Value = app.joints{5}.currentPos;

            %Set limits for the speed slider
            app.SldSpeed.Limits = [1,100];
        end

        function initializeTimer(app)
            % Setup the timer for sending commands to the simulation
            app.timer_sendPos = timer('ExecutionMode', 'fixedRate', ...
                'Period', 0.1, ...  % Update every 100ms
                'TimerFcn', @(~,~)app.updateJointPositions());
            start(app.timer_sendPos);
        end

        function updateJointPositions(app)
            if app.connection.clientID > -1 && app.connection.isSimulationRunning
                % Retrieve the current position for each joint from CoppeliaSim
                for i = 1:length(app.joints)  % Loop through the lenght of object array

                    %check if the joint is revolute or prismatic
                    if app.joints{i}.type == app.structJointTypes.REVOLUTE && app.joints{i}.atPos == false
                        % Get the current position of the joint
                        app.joints{i}.getCurrentPosition(app.connection.sim, app.connection.clientID);
                        field = sprintf('FldJoint%d', i-1);
                        app.(field).Value = app.joints{i}.currentPos;
                    end
                end
                % Now, update the end-effector position
                app.updateEndEffectorPosition();  % Call the function to calculate and update XYZ
            end
        end
  
        function updateEndEffectorPosition(app)
            % Step 1: Initialize the Kinematics class with DH parameters
            kinematics = Kinematics(app.DH_params);

            % Step 2: Get current joint positions from the app
            jointPositions = zeros(1, 5);
            for i = 1:length(app.joints)
                if app.joints{i}.type == app.structJointTypes.REVOLUTE
                    jointPositions(i) = app.joints{i}.currentPos;  % Get joint positions
                end
            end

            % Step 3: Calculate the end-effector position using forward kinematics
            endEffectorPos = kinematics.getEndEffectorPosition(jointPositions);

            % Step 4: Update the XYZ fields in the UI with the new end-effector position
            app.FldEoA_X.Value  = endEffectorPos(1);
            app.FldEoA_Y.Value  = endEffectorPos(2);
            app.FldEoA_Z.Value  = endEffectorPos(3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize RobotConnection
            app.connection = RobotConnection();
            app.BtnConnect.Text = 'Conect';
            app.BtnConnect.Icon = 'icons/Connect.png';
        end

        % Button pushed function: BtnConnect
        function BtnConnectButtonPushed(app, event)
            % Check the current state of the connection
            if app.connection.clientID == -1
                % Attempt to connect
                success = app.connection.connect();
                if success
                    % Retrieve and initialize joint handles
                    app.retrieveAndInitializeJoints();

                    % Setup sliders dynamically
                    app.setupSliders();

                    % Disable UI controls after successful connection
                    app.disableUIControls();

                    % Change button icon and text to indicate "Disconnect"
                    app.BtnConnect.Text = 'Disconnect';
                    app.BtnConnect.Icon = 'icons/Disconnect.png';  % Assuming you have an icon file for disconnect

                    %enable Simulation Button
                    app.BtnSimulate.Enable = "on";
                    app.BtnSimulate.Icon = "icons/Start.png";
                end
            else
                % If already connected, disconnect
                app.connection.disconnect();
                % Disable UI controls after successful connection
                    app.disableUIControls();

                % Change button icon and text to indicate "Connect"
                app.BtnConnect.Text = 'Connect';
                app.BtnConnect.Icon = 'icons/Connect.png';  % Assuming you have an icon file for connect
            end
        end

        % Button pushed function: BtnGripperOpn
        function BtnGripperOpnButtonPushed(app, event)
            app.joints{6}.setPosition(app.connection.sim, app.connection.clientID, app.joints{6}.lowerLimit); % Gripper 1
            app.joints{7}.setPosition(app.connection.sim, app.connection.clientID, app.joints{7}.lowerLimit); % Gripper 2
        end

        % Button pushed function: BtnGripperCls
        function BtnGripperClsButtonPushed(app, event)
            app.joints{6}.setPosition(app.connection.sim, app.connection.clientID, app.joints{6}.upperLimit); % Gripper 1
            app.joints{7}.setPosition(app.connection.sim, app.connection.clientID, app.joints{7}.upperLimit); % Gripper 2
        end

        % Value changing function: SldJoint0
        function SldJoint0ValueChanging(app, event)
            changingValue = event.Value;
            app.joints{1}.setPosition(app.connection.sim, app.connection.clientID, changingValue); 
        end

        % Value changing function: SldJoint1
        function SldJoint1ValueChanging(app, event)
            changingValue = event.Value;
            app.joints{2}.setPosition(app.connection.sim, app.connection.clientID, changingValue); 
        end

        % Value changing function: SldJoint2
        function SldJoint2ValueChanging(app, event)
            changingValue = event.Value;
            app.joints{3}.setPosition(app.connection.sim, app.connection.clientID, changingValue); 
        end

        % Value changing function: SldJoint3
        function SldJoint3ValueChanging(app, event)
            changingValue = event.Value;
            app.joints{4}.setPosition(app.connection.sim, app.connection.clientID, changingValue); 
        end

        % Value changing function: SldJoint4
        function SldJoint4ValueChanging(app, event)
            changingValue = event.Value;
            app.joints{5}.setPosition(app.connection.sim, app.connection.clientID, changingValue); 
        end

        % Button pushed function: BtnSimulate
        function BtnSimulateButtonPushed(app, event)
            % Check if the client is connected
            if app.connection.clientID > -1
                % Determine if the simulation is currently running
                if ~app.connection.isSimulationRunning
                    %Must get current position and set joint pos to current
                    %position, coppeliasim retains last commanded values
                    %and on simulation start it moves the arm on the last
                    %commanded pos causing unwanted movement at the
                    %simulation beginning. 
                    for i = 1:length(app.joints)
                        % Get current position
                        app.joints{i}.getCurrentPosition(app.connection.sim, app.connection.clientID);

                        % Set target position to the current position (to prevent movement)
                        app.joints{i}.setPosition(app.connection.sim, app.connection.clientID, app.joints{i}.currentPos);
                    end
                    
                    % Start the simulation
                    app.connection.startSimulation();

                    % Check if the simulation started successfully
                    if app.connection.isSimulationRunning
                        %set the initial speed
                        app.setInitialSpeedAfterSimStart();
                        % Enable UI controls
                        app.enableUIControls();
                        % Start the timer for updating joint positions
                        app.initializeTimer();
                        % Update button icon to indicate "Stop Simulation"
                        app.BtnSimulate.Icon = 'icons/Stop.png';  % stop simulation icon
                    end
                else
                    % Stop the simulation
                    app.connection.stopSimulation();
                    
                    % Stop the timer if it's running
                    if isvalid(app.timer_sendPos)
                        stop(app.timer_sendPos);
                    end
                    % Check if the simulation stopped successfully
                    if ~app.connection.isSimulationRunning
                        % Disable UI controls
                        app.disableUIControls();

                        % Update button icon to indicate "Start Simulation"
                        app.BtnSimulate.Icon = 'icons/Start.png';  % start simulation icon
                        app.BtnSimulate.Enable = "on";
                    end
                end
            else
                disp('Please connect to CoppeliaSim before starting the simulation.');
            end
        end

        % Value changed function: SldSpeed
        function SldSpeedValueChanged(app, event)
            newSpeedValue  = app.SldSpeed.Value;
            % Delegate the speed setting to each joint
            for i = 1:length(app.joints)
                app.joints{i}.setVelocity(app.connection.sim, app.connection.clientID, newSpeedValue);
            end
            %Delegate the speed setting to base control
           % base_allSpeed = app.baseControl.allSpeed;
           %app.baseControl.allSpeed = (newSpeedValue * 50.0) / 100;
           app.wheelsSpeed = (newSpeedValue * 5.0) / 100;
        end

        % Value changed function: BtnForward
        function BtnForwardValueChanged(app, event)
            value = app.BtnForward.Value;
            if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.FORWARD,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnBackward
        function BtnBackwardValueChanged(app, event)
            value = app.BtnBackward.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.BACKWARD,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnLeft
        function BtnLeftValueChanged(app, event)
            value = app.BtnLeft.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.LEFT,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnRight
        function BtnRightValueChanged(app, event)
            value = app.BtnRight.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.RIGHT,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnCW
        function BtnCWValueChanged(app, event)
            value = app.BtnCW.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.CLOCKWISE,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnCCW
        function BtnCCWValueChanged(app, event)
            value = app.BtnCCW.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.COUNTER_CLOCKWISE,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnFwdLeft
        function BtnFwdLeftValueChanged(app, event)
            value = app.BtnFwdLeft.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_FL,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnFwdRight
        function BtnFwdRightValueChanged(app, event)
            value = app.BtnFwdRight.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_FR,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnBwdLeft
        function BtnBwdLeftValueChanged(app, event)
            value = app.BtnBwdLeft.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_BL,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnBwdRight
        function BtnBwdRightValueChanged(app, event)
            value = app.BtnBwdRight.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_BR,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Button pushed function: BtnCamera
        function BtnCameraButtonPushed(app, event)
            app.visionSensor.captureAndDisplayImage(app.connection.sim, app.connection.clientID, app);
        end

        % Button pushed function: BtnStream
        function BtnStreamButtonPushed(app, event)
            % Toggle streaming state
            app.visionSensor.isStreaming = ~app.visionSensor.isStreaming;

            if app.visionSensor.isStreaming
                app.BtnStream.Text = 'Stop';
                % Start streaming
                while app.visionSensor.isStreaming
                    app.visionSensor.captureAndDisplayImage(app.connection.sim, app.connection.clientID, app);
                    pause(0.1);  % Control the frame rate of the stream
                end
            else
                app.BtnStream.Text = 'Start';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sensor')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @BtnConnectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.BtnSimulate = uibutton(app.UIFigure, 'push');
            app.BtnSimulate.ButtonPushedFcn = createCallbackFcn(app, @BtnSimulateButtonPushed, true);
            app.BtnSimulate.Icon = fullfile(pathToMLAPP, 'icons', 'Start.png');
            app.BtnSimulate.Enable = 'off';
            app.BtnSimulate.Text = '';
            app.Joint1Label = uilabel(app.UIFigure);
            app.Joint1Label.Enable = 'off';
            app.Joint1Label.Text = 'Joint 1';
            app.SldJoint1 = uislider(app.UIFigure);
            app.SldJoint1.ValueChangingFcn = createCallbackFcn(app, @SldJoint1ValueChanging, true);
            app.SldJoint1.Enable = 'off';
            app.Joint0Label = uilabel(app.UIFigure);
            app.Joint0Label.Enable = 'off';
            app.Joint0Label.Text = 'Joint 0';
            app.SldJoint0 = uislider(app.UIFigure);
            app.SldJoint0.ValueChangingFcn = createCallbackFcn(app, @SldJoint0ValueChanging, true);
            app.SldJoint0.Enable = 'off';
            app.Joint2Label = uilabel(app.UIFigure);
            app.Joint2Label.Enable = 'off';
            app.Joint2Label.Text = 'Joint 2';
            app.SldJoint2 = uislider(app.UIFigure);
            app.SldJoint2.ValueChangingFcn = createCallbackFcn(app, @SldJoint2ValueChanging, true);
            app.SldJoint2.Enable = 'off';
            app.Joint4Label = uilabel(app.UIFigure);
            app.Joint4Label.Enable = 'off';
            app.Joint4Label.Text = 'Joint 4';
            app.SldJoint4 = uislider(app.UIFigure);
            app.SldJoint4.ValueChangingFcn = createCallbackFcn(app, @SldJoint4ValueChanging, true);
            app.SldJoint4.Enable = 'off';
            app.Joint3Label = uilabel(app.UIFigure);
            app.Joint3Label.Enable = 'off';
            app.Joint3Label.Text = 'Joint 3';
            app.SldJoint3 = uislider(app.UIFigure);
            app.SldJoint3.ValueChangingFcn = createCallbackFcn(app, @SldJoint3ValueChanging, true);
            app.SldJoint3.Enable = 'off';
            app.SPEEDLabel = uilabel(app.UIFigure);
            app.SPEEDLabel.Enable = 'off';
            app.SPEEDLabel.Text = 'SPEED';
            app.SldSpeed = uislider(app.UIFigure);
            app.SldSpeed.ValueChangedFcn = createCallbackFcn(app, @SldSpeedValueChanged, true);
            app.SldSpeed.Enable = 'off';
            app.FldJoint0 = uieditfield(app.UIFigure, 'numeric');
            app.FldJoint0.Editable = 'off';
            app.FldJoint0.Enable = 'off';
            app.FldJoint1 = uieditfield(app.UIFigure, 'numeric');
            app.FldJoint1.Editable = 'off';
            app.FldJoint1.Enable = 'off';
            app.FldJoint2 = uieditfield(app.UIFigure, 'numeric');
            app.FldJoint2.Editable = 'off';
            app.FldJoint2.Enable = 'off';
            app.FldJoint3 = uieditfield(app.UIFigure, 'numeric');
            app.FldJoint3.Editable = 'off';
            app.FldJoint3.Enable = 'off';
            app.FldJoint4 = uieditfield(app.UIFigure, 'numeric');
            app.FldJoint4.Editable = 'off';
            app.FldJoint4.Enable = 'off';
            app.BtnForward = uibutton(app.UIFigure, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @BtnForwardValueChanged, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.UIFigure, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @BtnRightValueChanged, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.UIFigure, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @BtnBackwardValueChanged, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.UIFigure, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @BtnLeftValueChanged, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFwdRight = uibutton(app.UIFigure, 'state');
            app.BtnFwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnFwdRightValueChanged, true);
            app.BtnFwdRight.Enable = 'off';
            app.BtnFwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'FwdRight.png');
            app.BtnFwdRight.Text = '';
            app.BtnBwdRight = uibutton(app.UIFigure, 'state');
            app.BtnBwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnBwdRightValueChanged, true);
            app.BtnBwdRight.Enable = 'off';
            app.BtnBwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'BwdRight.png');
            app.BtnBwdRight.Text = '';
            app.BtnFwdLeft = uibutton(app.UIFigure, 'state');
            app.BtnFwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnFwdLeftValueChanged, true);
            app.BtnFwdLeft.Enable = 'off';
            app.BtnFwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'FwdLeft.png');
            app.BtnFwdLeft.Text = '';
            app.BtnBwdLeft = uibutton(app.UIFigure, 'state');
            app.BtnBwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnBwdLeftValueChanged, true);
            app.BtnBwdLeft.Enable = 'off';
            app.BtnBwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'BwdLeft.png');
            app.BtnBwdLeft.Text = '';
            app.BtnStream = uibutton(app.UIFigure, 'push');
            app.BtnStream.ButtonPushedFcn = createCallbackFcn(app, @BtnStreamButtonPushed, true);
            app.BtnStream.Icon = fullfile(pathToMLAPP, 'icons', 'streaming.png');
            app.BtnStream.Enable = 'off';
            app.BtnStream.Text = '';
            app.BtnCamera = uibutton(app.UIFigure, 'push');
            app.BtnCamera.ButtonPushedFcn = createCallbackFcn(app, @BtnCameraButtonPushed, true);
            app.BtnCamera.Icon = fullfile(pathToMLAPP, 'icons', 'camera.png');
            app.BtnCamera.Enable = 'off';
            app.BtnCamera.Text = '';
            app.BtnGripperOpn = uibutton(app.UIFigure, 'push');
            app.BtnGripperOpn.ButtonPushedFcn = createCallbackFcn(app, @BtnGripperOpnButtonPushed, true);
            app.BtnGripperOpn.Enable = 'off';
            app.BtnGripperOpn.Text = {'GRIPPER '; 'OPEN'};
            app.BtnGripperCls = uibutton(app.UIFigure, 'push');
            app.BtnGripperCls.ButtonPushedFcn = createCallbackFcn(app, @BtnGripperClsButtonPushed, true);
            app.BtnGripperCls.Enable = 'off';
            app.BtnGripperCls.Text = {'GRIPPER '; 'CLOSE'};
            app.BtnCCW = uibutton(app.UIFigure, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @BtnCCWValueChanged, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.UIFigure, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @BtnCWValueChanged, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            app.FldEoA_X = uieditfield(app.UIFigure, 'numeric');
            app.FldEoA_X.Editable = 'off';
            app.FldEoA_X.Enable = 'off';
            app.FldEoA_Y = uieditfield(app.UIFigure, 'numeric');
            app.FldEoA_Y.Editable = 'off';
            app.FldEoA_Y.Enable = 'off';
            app.FldEoA_Z = uieditfield(app.UIFigure, 'numeric');
            app.FldEoA_Z.Editable = 'off';
            app.FldEoA_Z.Enable = 'off';
            app.Lbl_EoA = uilabel(app.UIFigure);
            app.Lbl_EoA.Enable = 'off';
            app.Lbl_EoA.Text = {'EoA'; 'POSITION'};
            app.Lbl_EoA_X = uilabel(app.UIFigure);
            app.Lbl_EoA_X.Enable = 'off';
            app.Lbl_EoA_X.Text = 'X';
            app.Lbl_EoA_Y = uilabel(app.UIFigure);
            app.Lbl_EoA_Y.Enable = 'off';
            app.Lbl_EoA_Y.Text = 'Y';
            app.Lbl_EoA_Z = uilabel(app.UIFigure);
            app.Lbl_EoA_Z.Enable = 'off';
            app.Lbl_EoA_Z.Text = 'Z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end